classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FullimagesasROIselectiononlyNoGridpossibleLabel  matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.EditField
        MaxEditFieldLabel              matlab.ui.control.Label
        ManualsegmentationButton       matlab.ui.control.Button
        ImportLabel_2                  matlab.ui.control.Label
        ImportFolder                   matlab.ui.control.Button
        SWEImageAnalysisToolLabel      matlab.ui.control.Label
        noUltrasoundLabel              matlab.ui.control.Label
        CurrentdirectoryLabel          matlab.ui.control.Label
        ROISelectionLabel              matlab.ui.control.Label
        OKButtonROI                    matlab.ui.control.StateButton
        RefreshButton                  matlab.ui.control.Button
        DrawRectangleButton            matlab.ui.control.Button
        toEditField                    matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        FromEditField                  matlab.ui.control.NumericEditField
        FromEditFieldLabel             matlab.ui.control.Label
        GridOffset_ySpinner            matlab.ui.control.Spinner
        GridOffsetyLabel               matlab.ui.control.Label
        GridOffset_xSpinner            matlab.ui.control.Spinner
        GridOffsetxLabel               matlab.ui.control.Label
        FrameNoSpinner                 matlab.ui.control.Spinner
        ViewFrameLabel                 matlab.ui.control.Label
        ImportedFileTextArea           matlab.ui.control.TextArea
        ImportedFileTextAreaLabel      matlab.ui.control.Label
        NoofFramesTextArea             matlab.ui.control.TextArea
        NoofFramesLabel                matlab.ui.control.Label
        FrameRangeLabel                matlab.ui.control.Label
        SelectallframesorframerangeDefaultAllFramesLabel  matlab.ui.control.Label
        OKButton_FrameRange            matlab.ui.control.Button
        AllFramesButton                matlab.ui.control.Button
        GridReferenceImageLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Offset_Info_2                  matlab.ui.control.Label
        Offset_Info                    matlab.ui.control.Label
        EnteroffsetvalueinmmforgridDefault2mmLabel  matlab.ui.control.Label
        SetOffsetValueLabel            matlab.ui.control.Label
        OKOffsetButton_y               matlab.ui.control.Button
        OKOffsetButton_x               matlab.ui.control.Button
        ImportCheckLabel               matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        ExportStatusLabel_2            matlab.ui.control.Label
        ExportStatusLabel              matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ImageViewerLabel               matlab.ui.control.Label
        ImageTypeSelectionButtonGroup  matlab.ui.container.ButtonGroup
        ROISelectionButton             matlab.ui.control.RadioButton
        SWEImagewithGridButton         matlab.ui.control.RadioButton
        SWEImageButton                 matlab.ui.control.RadioButton
        FrameButton                    matlab.ui.control.RadioButton
        ViewImageLabel                 matlab.ui.control.Label
        OKButtonViewFrame              matlab.ui.control.Button
        FileInformationLabel           matlab.ui.control.Label
        DirectoryLabel                 matlab.ui.control.Label
        ImportLabel                    matlab.ui.control.Label
        Directory                      matlab.ui.control.Button
        ImportFile                     matlab.ui.control.Button
        ExportResultTableButton        matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)

        % Add path for icons and code
        

        File % Name of file
        FilePath % Path of file
        FullFileName % Name of file with path        
        FileNameXLS % Dir and name of output xls file
        OutputPath % Path where .xls file will be saved
        StopExecution % Button to stop execution of program 
        info % Dicom info
        nFrames % Number of frames
        chosenImage % Image selected to display
        xOffset % Offset in x direction
        yOffset % Offset in y direction

        fullFolderPath % Folder containing files for batch processing
        batchProcessing % Variable indicating batch processing
        fullFileNames % All files in fodler
        numFiles % Number of files in folder

        maxValue % maximal SWE value, by default set to 300

        % Save coordinates to avoid cropping every image
        positionBmode % Location of single Bmode image (output of segmentBmodeNew)
        position % position of SWE image, taken from Bmode image (output of GetSWEImg_m)

        % Scale factor
        scaleFactorHeight
        scaleFactorWidth
        % Threshold for binary image for scale factor
        threshBW

        % Frame range
        frameFirst
        frameLast

        % Save drawn shape + coordinates
        Shape
        shapeLocs
        imShapeRoi

        % Check if path selected and file imported
        pathSelected = 0;
        fileImported = 0;   

        Fs % For done sound
        y % For done sound

        Fs2 % For error sound
        y2 % For error sound

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('codeIcons', 'codeFiles');
            %addpath('./codeFiles');
            
            % Add icons
            app.Directory.Icon = ('dir.png');
            app.ImportFile.Icon = ('import.png');
            app.DrawRectangleButton.Icon = ('draw_rectangle_icon.png');
            app.RefreshButton.Icon = ('refresh.png');
            app.ImportFolder.Icon = ('multipleFiles.png');
            app.Image.ImageSource = ('ROI-Grid-Elastogramm-zentriert-refImage.png');

            % Add audio - Sound from Zapsplat.com  
            [app.y, app.Fs] = audioread('alert.mp3');
            [app.y2, app.Fs2] = audioread('error.mp3');
        end

        % Button pushed function: ImportFile
        function ImportFileButtonPushed(app, event)
            % Button location: Second button from top left
            
            [app.File, app.FilePath, ~] = uigetfile('..\*.*'); % Select file
            drawnow; % Put window in foreground
            figure(app.UIFigure)

            % Error if file has not been imported
            if app.File == 0
                app.ImportCheckLabel.FontColor = [1 0 0];
                app.ImportCheckLabel.Text = 'Please import a DICOM file!';
            else

                app.FullFileName = [app.FilePath app.File]; % File name and path
                [~, ~, extension] = fileparts(app.FullFileName); % check for extension
    
                % If file has no extension or
                % file does not have .dcm extension
                if not(isequal(extension, '')) 
                    if not(isequal(extension, '.dcm'))
                        app.ImportCheckLabel.FontColor = [1 0 0];
                        app.ImportCheckLabel.Text = 'Please import a DICOM file!';
                    end                     
    
                else
                    app.ImportCheckLabel.FontColor = [0 0 0];
                    app.ImportCheckLabel.Text = 'File imported.';
                    app.ExportStatusLabel_2.Text = '';
                    app.Lamp.Color = [0.902 0.902 0.902];
                    app.fileImported = 1;

                    app.batchProcessing = 0; % No batch processing
                   
    
                    % File name for Excel table
                    app.FileNameXLS = [app.OutputPath,'\', app.File, '_OffsetXY_', num2str(app.xOffset), '_', num2str(app.yOffset), '.xlsx';]; 
                    
                    % Get file info
                    app.info = dicominfo(app.FullFileName);
                    app.nFrames = app.info.NumberOfFrames;
        
                    % Output file name in text area 'Imported File:'
                    app.ImportedFileTextArea.Value = app.File;
                    figure(app.UIFigure);
        
                    % Output number of frames in text area 'No. of Frames'
                    app.NoofFramesTextArea.Value = int2str(app.nFrames);
                    figure(app.UIFigure);
        
                    % Change spinner value to 1
                    app.FrameNoSpinner.Value = 1;
    
                    % Change offset value to 2 mm
                    app.GridOffset_xSpinner.Value = 2;
                    app.GridOffset_ySpinner.Value = 2;
    
                    app.xOffset = app.GridOffset_xSpinner.Value;
                    app.yOffset = app.GridOffset_ySpinner.Value;
    
                    app.Offset_Info.Text = ['Offset X: ' num2str(app.xOffset) ' mm'];
                    app.Offset_Info_2.Text = ['Y: ' num2str(app.yOffset) ' mm'];
        
    
                    % Change radio button selection
                    app.ImageTypeSelectionButtonGroup.SelectedObject = app.FrameButton;
    
                    % Change range
                    app.FromEditField.Value = 1;
                    app.toEditField.Value = app.nFrames;
                    app.frameFirst = app.FromEditField.Value;
                    app.frameLast = app.toEditField.Value;
                    % Range label
                    app.FrameRangeLabel.Text = ['Frame range: ' num2str(app.frameFirst) ' - ' num2str(app.frameLast)];
    
    
                    % First frame
                    app.chosenImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
    
    
                    % Find location of SWE Image       
                    % Get US image
                    %this code is doubble, I am unsure whether it is really
                    %redundant
                    %version for manual only:
                    %[app.positionBmode,app.threshBW, app.position, ~] = segmentElastogram(app.chosenImage);
                    %display image
                    figure, imshow(app.chosenImage); %evtl muss ich hier das Bild anders anzeigen
                    %select start and endpoint of B-mode image
                    disp({'Please select the start point, i.e. upper left corner of B-mode image.'});
                    pointStart = drawpoint;
                    disp({'Please select the end point, i.e. lower right corner of B-mode image.'});
                    pointEnd = drawpoint;
                    app.positionBmode = [pointStart.Position(2), pointEnd.Position(2), pointStart.Position(1), pointEnd.Position(1)];
                    app.positionBmode = uint16(app.positionBmode);
                    %%select start B-mode image buttom
                    %display B-mode image top
                    %select start and end-point of SWE image
                    %figure, imshow(app.chosenImage(app.positionBmode(1):app.positionBmode(2)), app.positionBmode(3):app.positionBmode(4));
                    croppedBmodeIm = app.chosenImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);
                    figure, imshow(croppedBmodeIm);
                    pointStartSWE = drawpoint;
                    pointEndSWE = drawpoint;
                    app.position = [pointStartSWE.Position(2), pointEndSWE.Position(2), pointStartSWE.Position(1), pointEndSWE.Position(1)];
                    app.position = uint16(app.position);
                    %get threshold sw 
                    app.threshBW = 0.05;
                    close all;


                    %[Bimg, app.positionBmode, ~, noUltrasound, app.threshBW] = segmentBmodeNew(app.chosenImage);
    
%                     if noUltrasound == 1
%                         app.noUltrasoundLabel.Text =  'No ultrasound image detected! Please specify a different file before continuing.';   
%                         %app.UIAxes.Color = [0.90,0.90,0.90];
%                         app.noUltrasoundLabel.BackgroundColor = 'yellow';
%                         imshow(1, 'Parent', app.UIAxes);
%                     else
%                         % Show first frame
                        app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];
                        imshow(app.chosenImage, 'Parent', app.UIAxes);
%                         app.noUltrasoundLabel.Text = '';
%                         app.noUltrasoundLabel.BackgroundColor = 'none';
%     
%                         % get elastogram position
%                         [~, app.position] = GetSWEImg_m(Bimg);
        
                        % Get scale factor, set to 1 only for this version!
                       %[app.scaleFactorHeight, app.scaleFactorWidth] = scale(app.chosenImage, app.positionBmode, app.threshBW); 
                        app.scaleFactorHeight  = 1;%CalcScaleFactor( app.chosenImage, app.positionBmode );
                        %app.scaleFactorHeight = app.scaleFactor;
                        app.scaleFactorWidth = app.scaleFactorHeight;
%                     end


                end

            end


        end

        % Button pushed function: Directory
        function DirectoryButtonPushed(app, event)
            % Button location: First button from top left

            app.OutputPath = uigetdir(path); % Path where results will be saved
            drawnow; % Put window in foreground
            figure(app.UIFigure)
            app.pathSelected = 1;
            app.ExportStatusLabel_2.Text = '';
            app.Lamp.Color = [0.902 0.902 0.902];

            app.CurrentdirectoryLabel.Text = ['Current directory: ', app.OutputPath];

        end

        % Button pushed function: ExportResultTableButton
        function ExportResultTableButtonPushed(app, event)
            % Button location: Bottom, above 'Export Status' label
            
            % read in set maximum value 
            app.maxValue = str2num(app.MaxEditField.Value);


            % First, check if path has been selected
            if app.pathSelected ~= 1                
                app.Lamp.Color = [1 0 0]; % Red
                app.ExportStatusLabel_2.Text = 'Select directory first!';

                % Check if file or folder have been imported
            elseif app.fileImported ~= 1 && app.batchProcessing ~= 1
                app.Lamp.Color = [1 0 0]; % Red
                app.ExportStatusLabel_2.Text = 'Import file first!';

            
            % Either do ROI analysis, or grid analysis
            
            % If ROI analysis:
            elseif app.ImageTypeSelectionButtonGroup.SelectedObject == app.ROISelectionButton


                % Check if ROI has been selected
                if app.OKButtonROI.Value == 1


                    app.ExportStatusLabel_2.Text = 'ROI analysis in progress...'; 
                    app.Lamp.Color = [1 1 0]; % Yellow
                    pause(0.0000000001);

                    % Check for bacth processing and loop through all
                    % folder contents, otherwise just output result of
                    % single input file
                    if app.batchProcessing == 1
                        

                        for k = 1 : app.numFiles
                            app.File = app.fullFileNames{k};
                            [~] = main_ROIAnalysis(app.File, app.fullFolderPath, ...
                                app.OutputPath, app.frameFirst, app.frameLast, ...
                                app.shapeLocs, app.positionBmode, app.position, ...
                                app.maxValue);

                        end

                    else


                    % Run function
                    [~] = main_ROIAnalysis(app.File, app.FilePath, app.OutputPath, ...
                        app.frameFirst, app.frameLast, app.shapeLocs, ...
                        app.positionBmode, app.position, app.maxValue);
                    end


                    % Inform user of completion
                    app.Lamp.Color = [0 1 0]; % Green
                    app.ExportStatusLabel_2.Text = 'Export successful.';

                    
                else
                    app.Offset_Info.Text = 'Select ROI first!';

                end


            else % Grid analysis ------------------------------------------------------------------

                app.ExportStatusLabel_2.Text = 'Grid analysis in progress...'; 
                app.Lamp.Color = [1 1 0]; % Yellow
                pause(0.0000000001);

                % Either do batch processing
                if app.batchProcessing == 1
                        
                    for k = 1 : app.numFiles
                        app.File = app.fullFileNames{k};
                        [~] = main_GridAnalysis(app.File, app.fullFolderPath, ...
                            app.OutputPath, app.frameFirst, app.frameLast, ...
                            app.xOffset, app.yOffset, app.positionBmode, ...
                            app.position, app.maxValue);
                        % Is x and y offset calculated upon import?
                            
                    end
    
                else


                % Or single file grid analysis 
                [~] = main_GridAnalysis(app.File, app.FilePath, app.OutputPath, ...
                    app.frameFirst, app.frameLast, app.xOffset, app.yOffset, ...
                    app.positionBmode, app.position, app.maxValue);

                end
    
                % Inform user of completion
                app.Lamp.Color = [0 1 0]; % Green
                app.ExportStatusLabel_2.Text = 'Export successful.';


            end 


            % Notify end with sound
            if app.pathSelected == 1 && (app.fileImported == 1 || app.batchProcessing == 1) 
                sound(app.y, app.Fs);
            elseif app.pathSelected == 1 && (app.fileImported == 1 || app.batchProcessing == 1) && app.OKButtonROI.Value == 1
                sound(app.y, app.Fs);
            else
                sound(app.y2, app.Fs2);
            end


        end

        % Value changed function: NoofFramesTextArea
        function NoofFramesTextAreaValueChanged(app, event)
            %value = app.NoofFramesTextArea.Value;
            
        end

        % Value changed function: ImportedFileTextArea
        function ImportedFileTextAreaValueChanged(app, event)
            %value = app.ImportedFileTextArea.Value;
            
        end

        % Callback function
        function ExportStatusTextAreaValueChanged(app, event)
                   
        end

        % Value changed function: FrameNoSpinner
        function FrameNoSpinnerValueChanged(app, event)
            value = app.FrameNoSpinner.Value; 

            if value < 1 || value > app.nFrames
                app.Label.Text = ['Please enter a value between 1 and ' num2str(app.nFrames) '!'];
                app.FrameNoSpinner.Value = 1;
            else
                app.Label.Text = ' ';
            end
        end

        % Button pushed function: OKButtonViewFrame
        function OKButtonViewFramePushed(app, event)
            % Button location: Bottom left, next to 'Frame No.'
            
            % View image depending on radio button

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.FrameButton
                app.chosenImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
                app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];

            end

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.SWEImageButton
                
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);

                app.chosenImage = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];

            end


            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.SWEImagewithGridButton
                
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);

                % Get US image
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);

                % get elastogram
                SWEImg = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                % Draw grid
                [app.chosenImage, ~, ~] = drawGrid(SWEImg, app.scaleFactorHeight, app.scaleFactorWidth, app.xOffset, app.yOffset);
                
                % Show frame
                app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];
               

            end

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.ROISelectionButton

                % Show image
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);
                % get elastogram
                SWEImg = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                if app.OKButtonROI.Value == 1
                    app.chosenImage = SWEImg(app.shapeLocs(1):app.shapeLocs(2), app.shapeLocs(3):app.shapeLocs(4), :);
                    app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value) ' - ROI Selection'];

                else
                    app.chosenImage = SWEImg;
                    app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];

                end

            end
            
            
            %app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];
            imshow(app.chosenImage, 'Parent', app.UIAxes);
        end

        % Selection changed function: ImageTypeSelectionButtonGroup
        function ImageTypeSelectionButtonGroupSelectionChanged(app, event)
            % Button group location: beneath 'View Image' section
            
            %selectedButton = app.ImageTypeSelectionButtonGroup.SelectedObject;


            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.FrameButton
                app.chosenImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);

                % Label
                app.Offset_Info.Text = ['Offset X: ' num2str(app.xOffset) ' mm'];
                app.Offset_Info_2.Text = ['Y: ' num2str(app.yOffset) ' mm'];

            end

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.SWEImageButton
                
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);

                % get elastogram
                app.chosenImage = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                % Label
                app.Offset_Info.Text = ['Offset X: ' num2str(app.xOffset) ' mm'];
                app.Offset_Info_2.Text = ['Y: ' num2str(app.yOffset) ' mm'];


            end

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.SWEImagewithGridButton
                
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);

                % get elastogram
                SWEImg = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                % Draw grid
                [app.chosenImage, ~, ~] = drawGrid(SWEImg, app.scaleFactorHeight, app.scaleFactorWidth, app.xOffset, app.yOffset);


                % Label
                app.Offset_Info.Text = ['Offset X: ' num2str(app.xOffset) ' mm'];
                app.Offset_Info_2.Text = ['Y: ' num2str(app.yOffset) ' mm'];

            end

            % Hide draw tools until option selected
            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.ROISelectionButton
                app.DrawRectangleButton.Enable = true;
                app.OKButtonROI.Enable = true;
                app.RefreshButton.Enable = true;
                %app.DrawPolygonButton.Enable = true;

                
                % Show image
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);
                % get elastogram
                SWEImg = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                if app.OKButtonROI.Value == 1
                    app.chosenImage = SWEImg(app.shapeLocs(1):app.shapeLocs(2), app.shapeLocs(3):app.shapeLocs(4), :);

                else
                    app.chosenImage = SWEImg;

                end

                % Label
                app.Offset_Info.Text = '';
                app.Offset_Info_2.Text = '';

            else
                app.DrawRectangleButton.Enable = false;
                app.OKButtonROI.Enable = false;
                app.RefreshButton.Enable = false;
                %app.DrawPolygonButton.Enable = false;
            end


            imshow(app.chosenImage, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];


        end

        % Value changed function: GridOffset_xSpinner
        function GridOffset_xSpinnerValueChanged(app, event)
            value = app.GridOffset_xSpinner.Value; 

            if value < 0.1 || value > 10
                app.EnteroffsetvalueinmmforgridDefault2mmLabel.Text = 'Please enter a value between 0.1 and 10 mm!'; %num2str(app.nFrames) '!'];
                app.GridOffset_xSpinner.Value = 2;
                
            else
                app.EnteroffsetvalueinmmforgridDefault2mmLabel.Text = 'Enter offset value in mm for grid. (Default: 2 mm)';
            end


        end

        % Value changed function: GridOffset_ySpinner
        function GridOffset_ySpinnerValueChanged(app, event)
            value = app.GridOffset_ySpinner.Value;
            
            if value < 0.1 || value > 10
                app.EnteroffsetvalueinmmforgridDefault2mmLabel.Text = 'Please enter a value in mm between 1 and 10!'; %num2str(app.nFrames) '!'];
                app.GridOffset_ySpinner.Value = 2;
            else
                app.EnteroffsetvalueinmmforgridDefault2mmLabel.Text = 'Enter offset value in mm for grid. (Default: 2 mm)';
            end

        end

        % Button pushed function: OKOffsetButton_x
        function OKOffsetButton_xPushed(app, event)
            % Button location: In 'Set Offset Value' section, next to Grid
            % Offset X
            
            app.xOffset = app.GridOffset_xSpinner.Value;
            app.Offset_Info.Text = ['Offset X: ' num2str(app.xOffset) ' mm'];
            

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.SWEImagewithGridButton
                
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);

                % Get US image
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);

                % get elastogram
                SWEImg = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);

                % Draw grid
                [app.chosenImage, ~, ~] = drawGrid(SWEImg, app.scaleFactorHeight, app.scaleFactorWidth, app.xOffset, app.yOffset);


            end

            imshow(app.chosenImage, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];


        end

        % Button pushed function: OKOffsetButton_y
        function OKOffsetButton_yPushed(app, event)
            % Button location: In 'Set Offset Value' section, next to Grid
            % Offset Y
            
            app.yOffset = app.GridOffset_ySpinner.Value;
            app.Offset_Info_2.Text = ['Y: ' num2str(app.yOffset) ' mm'];
            

            if app.ImageTypeSelectionButtonGroup.SelectedObject == app.SWEImagewithGridButton
                
                chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);

                % Get US image
                croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);

                % get elastogram
                SWEImg = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);


                % Draw grid  
                [app.chosenImage, ~, ~] = drawGrid(SWEImg, app.scaleFactorHeight, app.scaleFactorWidth, app.xOffset, app.yOffset);

            end

            imshow(app.chosenImage, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];
        end

        % Value changed function: FromEditField
        function FromEditFieldValueChanged(app, event)
            %value = app.FromEditField.Value;
            
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            %value = app.toEditField.Value;
            
        end

        % Button pushed function: OKButton_FrameRange
        function OKButton_FrameRangePushed(app, event)
            % Button location: To the right of button 'All Frames'
            
            % Check for value within range
            if app.FromEditField.Value < 1 || app.FromEditField.Value > (app.nFrames-1)
                app.FromEditField.Value = 1;
                app.SelectallframesorframerangeDefaultAllFramesLabel.Text = "Please enter a value within range!";
            elseif app.toEditField.Value < 2 || app.toEditField.Value > app.nFrames
                app.toEditField.Value = app.nFrames;
                app.SelectallframesorframerangeDefaultAllFramesLabel.Text = "Please enter a value within range!";
            else
                app.SelectallframesorframerangeDefaultAllFramesLabel.Text = "Select all frames or frame range. (Default: All Frames)";
            end

            app.frameFirst = app.FromEditField.Value;
            app.frameLast = app.toEditField.Value;

            app.FrameNoSpinner.Value = app.FromEditField.Value;

            app.FrameRangeLabel.Text = ['Frame range: ' num2str(app.frameFirst) ' - ' num2str(app.frameLast)];

        end

        % Button pushed function: AllFramesButton
        function AllFramesButtonPushed(app, event)
            % Button location: Button labeled 'All Frames' beneath File
            % Information
            
            app.FromEditField.Value = 1;
            app.toEditField.Value = app.nFrames;
            
            app.frameFirst = app.FromEditField.Value;
            app.frameLast = app.toEditField.Value;

            app.FrameRangeLabel.Text = ['Frame range: ' num2str(app.frameFirst) ' - ' num2str(app.frameLast)];

            app.FrameNoSpinner.Value = 1;

        end

        % Button pushed function: DrawRectangleButton
        function DrawRectangleButtonPushed(app, event)
            % Button location: Beneath image axes, to the left
            
            app.Shape = drawrectangle(app.UIAxes, 'LineWidth', 1, 'Color', 'cyan');

        end

        % Callback function
        function OKButton_ShapePushed(app, event)
            % [xmin ymin width height] --> 
            % restructure to rowstart:rowend, colstart:colend

            shapeLocsTemp = app.Shape.Position;

            shapeRowStart = round(shapeLocsTemp(2));
            shapeRowEnd = round(shapeLocsTemp(2)+shapeLocsTemp(4));
            shapeColStart = round(shapeLocsTemp(1));
            shapeColEnd = round(shapeLocsTemp(1)+shapeLocsTemp(3));

            app.shapeLocs = [shapeRowStart, shapeRowEnd, shapeColStart, shapeColEnd];

            % Create ROI
            app.imShapeRoi = app.chosenImage(app.shapeLocs(1):app.shapeLocs(2), app.shapeLocs(3):app.shapeLocs(4), :);
           

            % Label
            app.Offset_Info.Text = 'ROI selected.';
            app.Offset_Info_2.Text = '';

            % Disable buttons
            app.DrawRectangleButton.Enable = false;
            %app.DrawPolygonButton.Enable = false;


            % Show selected shape
            imshow(app.imShapeRoi, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value) ' - ROI Selection'];


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Button location: Beneath image axes button, right-most button
            
            % Enable buttons
            app.DrawRectangleButton.Enable = true;
            %app.DrawPolygonButton.Enable = true;

            % Show image
            chImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
            croppedBmodeIm = chImage(app.positionBmode(1):app.positionBmode(2), app.positionBmode(3):app.positionBmode(4), :);
            % get elastogram
            app.chosenImage = croppedBmodeIm(app.position(1):app.position(2), app.position(3):app.position(4), :);


            % Deselct OK button
            app.OKButtonROI.Value = 0;

            % Clear label
            app.Offset_Info.Text = '';

            imshow(app.chosenImage, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];

        end

        % Value changed function: OKButtonROI
        function OKButtonROIValueChanged(app, event)
            % Button location: Benath image axes, second button from the
            % left

            %value = app.OKButtonROI.Value;
            
            app.OKButtonROI.Value = 1;

            % [xmin ymin width height] --> 
            % restructure to rowstart:rowend, colstart:colend

            shapeLocsTemp = app.Shape.Position;

            shapeRowStart = round(shapeLocsTemp(2));
            shapeRowEnd = round(shapeLocsTemp(2)+shapeLocsTemp(4));
            shapeColStart = round(shapeLocsTemp(1));
            shapeColEnd = round(shapeLocsTemp(1)+shapeLocsTemp(3));

            app.shapeLocs = [shapeRowStart, shapeRowEnd, shapeColStart, shapeColEnd];

            % Create ROI
            app.imShapeRoi = app.chosenImage(app.shapeLocs(1):app.shapeLocs(2), app.shapeLocs(3):app.shapeLocs(4), :);
           

            % Label
            app.Offset_Info.Text = 'ROI selected.';
            app.Offset_Info_2.Text = '';

            % Disable buttons
            app.DrawRectangleButton.Enable = false;
            %app.DrawPolygonButton.Enable = false;


            % Show selected shape
            imshow(app.imShapeRoi, 'Parent', app.UIAxes);
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value) ' - ROI Selection'];

        end

        % Button pushed function: ImportFolder
        function ImportFolderButtonPushed(app, event)
            % Button location: third button from top left
            
            app.fullFolderPath = uigetdir(path); % Path where files are

            % Ensure folder ahs been selected
            if app.fullFolderPath == 0
                app.ImportCheckLabel.FontColor = [1 0 0];
                app.ImportCheckLabel.Text = 'Please import a folder!';
                drawnow; % Put window in foreground
                figure(app.UIFigure)
            else
            
            drawnow; % Put window in foreground
            figure(app.UIFigure)
            app.batchProcessing = 1;
            app.fileImported = 0; % No individual file processing
            app.ExportStatusLabel_2.Text = '';
            app.Lamp.Color = [0.902 0.902 0.902];
            
            
            % Get and save all file names
            allFiles = dir(fullfile(app.fullFolderPath));
            app.fullFileNames = {allFiles(:).name};
            
            % Remove '.' and '..' in path
            i1 = find(strcmp(app.fullFileNames, '.')==1);
            app.fullFileNames(i1) = [];
            i2 = find(strcmp(app.fullFileNames, '..')==1);
            app.fullFileNames(i2) = [];           
            
            
            app.numFiles = length(app.fullFileNames);


            % View first image in folder
            app.ImportCheckLabel.Text = 'Folder imported.';  
            app.ImportCheckLabel.FontColor = [0 0 0];
            file1 = app.fullFileNames(1);
            fileName = [app.fullFolderPath, '\', file1];
            fileName = string(fileName);
            app.FullFileName = join(fileName, '');
            
            
            % Get file info
            app.info = dicominfo(app.FullFileName);
            app.nFrames = app.info.NumberOfFrames;
            
            % Output file name
            app.ImportedFileTextArea.Value = file1;
            figure(app.UIFigure);
            
            % Output number of frames
            app.NoofFramesTextArea.Value = int2str(app.nFrames);
            figure(app.UIFigure);
            
            % Change spinner value to 1
            app.FrameNoSpinner.Value = 1;
            
            % Change offset value to 2 mm
            app.GridOffset_xSpinner.Value = 2;
            app.GridOffset_ySpinner.Value = 2;
            
            app.xOffset = app.GridOffset_xSpinner.Value;
            app.yOffset = app.GridOffset_ySpinner.Value;
            
            app.Offset_Info.Text = ['Offset X: ' num2str(app.xOffset) ' mm'];
            app.Offset_Info_2.Text = ['Y: ' num2str(app.yOffset) ' mm'];
            
            
            % Change radio button selection
            app.ImageTypeSelectionButtonGroup.SelectedObject = app.FrameButton;
            
            % Change range
            app.FromEditField.Value = 1;
            app.toEditField.Value = app.nFrames;
            app.frameFirst = app.FromEditField.Value;
            app.frameLast = app.toEditField.Value;
            % Range label
            app.FrameRangeLabel.Text = ['Frame range: ' num2str(app.frameFirst) ' - ' num2str(app.frameLast)];
            
            
            % First frame
            app.chosenImage = dicomread(app.FullFileName, 'frames', app.FrameNoSpinner.Value);
            
            
            % Find location of SWE Image       
            % Get US image
            [app.positionBmode,app.threshBW, app.position, ~] = segmentElastogram(app.chosenImage);

            %[Bimg, app.positionBmode, ~, noUltrasound, app.threshBW] = segmentBmodeNew(app.chosenImage);
            
%             if noUltrasound == 1
%                 app.noUltrasoundLabel.Text =  'No ultrasound image detected! Please specify a different file before continuing.';
%                 app.noUltrasoundLabel.BackgroundColor = 'yellow';
%                 imshow(1, 'Parent', app.UIAxes);
%             else
                % Show first frame
                app.UIAxes.Title.String = ['File 1: Frame ' num2str(app.FrameNoSpinner.Value)];
                imshow(app.chosenImage, 'Parent', app.UIAxes);
%                 app.noUltrasoundLabel.Text = '';
%                 app.noUltrasoundLabel.BackgroundColor = 'none';
%             
%                 % get elastogram position
%                 [~, app.position] = GetSWEImg_m(Bimg);
%             
                % Get scale factor
                %[app.scaleFactorHeight, app.scaleFactorWidth] = scale(app.chosenImage, app.positionBmode, app.threshBW); 
                 app.scaleFactorHeight  = 1;%CalcScaleFactor( app.chosenImage, app.positionBmode );
                 %app.scaleFactorHeight = app.scaleFactor;
                 app.scaleFactorWidth = app.scaleFactorHeight;
%             end
            
            end
        end

        % Button pushed function: ManualsegmentationButton
        function ManualsegmentationButtonPushed(app, event)
            %display image
            figure, imshow(app.chosenImage); %evtl muss ich hier das Bild anders anzeigen
            %select start and endpoint of B-mode image
            disp({'Please select the start point, i.e. upper left corner of B-mode image.'});
            pointStart = drawpoint;
            disp({'Please select the end point, i.e. lower right corner of B-mode image.'});
            pointEnd = drawpoint;
            app.positionBmode = [pointStart.Position(2), pointEnd.Position(2), pointStart.Position(1), pointEnd.Position(1)];
            app.positionBmode = uint16(app.positionBmode);
            %%select start B-mode image buttom
            %display B-mode image top
            %select start and end-point of SWE image
            %figure, imshow(app.chosenImage(app.positionBmode(1):app.positionBmode(2)), app.positionBmode(3):app.positionBmode(4));
            croppedBmodeIm = app.chosenImage(app.positionBmode(2):app.positionBmode(4), app.positionBmode(1):app.positionBmode(3), :);
            figure, imshow(croppedBmodeIm);
            pointStartSWE = drawpoint;
            pointEndSWE = drawpoint;
            app.position = [pointStartSWE.Position(2), pointEndSWE.Position(2), pointStartSWE.Position(1), pointEndSWE.Position(1)];
            app.position = uint16(app.position);
            %get threshold sw 
            app.threshBW = 0.05;
            close all;
            %display image
            app.UIAxes.Title.String = ['Frame ' num2str(app.FrameNoSpinner.Value)];
            imshow(app.chosenImage, 'Parent', app.UIAxes);
            % Get scale factor
            app.scaleFactorHeight  = 1;%CalcScaleFactor( app.chosenImage, app.positionBmode );
            app.scaleFactorWidth = app.scaleFactorHeight;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ExportResultTableButton = uibutton(app.UIFigure, 'push');
            app.ExportResultTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultTableButtonPushed, true);
            app.ExportResultTableButton.Tooltip = {'Export .xslx file of results'};
            app.ExportResultTableButton.Text = 'Export Result Table';
            app.ImportFile = uibutton(app.UIFigure, 'push');
            app.ImportFile.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFile.Icon = 'import.png';
            app.ImportFile.Tooltip = {'Import DICOM file'};
            app.ImportFile.Text = '';
            app.Directory = uibutton(app.UIFigure, 'push');
            app.Directory.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.Directory.Icon = 'dir.png';
            app.Directory.Tooltip = {'Select directory for output excel file'};
            app.Directory.Text = '';
            app.ImportLabel = uilabel(app.UIFigure);
            app.ImportLabel.Text = 'Import File';
            app.DirectoryLabel = uilabel(app.UIFigure);
            app.DirectoryLabel.Text = 'Directory';
            app.FileInformationLabel = uilabel(app.UIFigure);
            app.FileInformationLabel.Text = 'File Information';
            app.OKButtonViewFrame = uibutton(app.UIFigure, 'push');
            app.OKButtonViewFrame.ButtonPushedFcn = createCallbackFcn(app, @OKButtonViewFramePushed, true);
            app.OKButtonViewFrame.Tooltip = {'View selected frame'};
            app.OKButtonViewFrame.Text = 'OK';
            app.ViewImageLabel = uilabel(app.UIFigure);
            app.ViewImageLabel.Text = 'View Image';
            app.ImageTypeSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageTypeSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageTypeSelectionButtonGroupSelectionChanged, true);
            app.ImageTypeSelectionButtonGroup.Tooltip = {'Select ''ROI Selection'' to define and analyse a region of interest. All other options refer to grid analysis.'};
            app.ImageTypeSelectionButtonGroup.Title = 'Image Type Selection';
            app.FrameButton = uiradiobutton(app.ImageTypeSelectionButtonGroup);
            app.FrameButton.Text = 'Frame';
            app.FrameButton.Value = true;
            app.SWEImageButton = uiradiobutton(app.ImageTypeSelectionButtonGroup);
            app.SWEImageButton.Text = 'SWE Image';
            app.SWEImagewithGridButton = uiradiobutton(app.ImageTypeSelectionButtonGroup);
            app.SWEImagewithGridButton.Text = 'SWE Image with Grid';
            app.ROISelectionButton = uiradiobutton(app.ImageTypeSelectionButtonGroup);
            app.ROISelectionButton.Text = 'ROI Selection';
            app.ImageViewerLabel = uilabel(app.UIFigure);
            app.ImageViewerLabel.Text = 'Image Viewer';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ExportStatusLabel = uilabel(app.UIFigure);
            app.ExportStatusLabel.Text = 'Export Status:';
            app.ExportStatusLabel_2 = uilabel(app.UIFigure);
            app.ExportStatusLabel_2.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.ImportCheckLabel = uilabel(app.UIFigure);
            app.ImportCheckLabel.Text = ' ';
            app.OKOffsetButton_x = uibutton(app.UIFigure, 'push');
            app.OKOffsetButton_x.ButtonPushedFcn = createCallbackFcn(app, @OKOffsetButton_xPushed, true);
            app.OKOffsetButton_x.Tooltip = {'Confirm X offset'};
            app.OKOffsetButton_x.Text = 'OK';
            app.OKOffsetButton_y = uibutton(app.UIFigure, 'push');
            app.OKOffsetButton_y.ButtonPushedFcn = createCallbackFcn(app, @OKOffsetButton_yPushed, true);
            app.OKOffsetButton_y.Tooltip = {'Confirm Y offset'};
            app.OKOffsetButton_y.Text = 'OK';
            app.SetOffsetValueLabel = uilabel(app.UIFigure);
            app.SetOffsetValueLabel.Text = 'Set Offset Value';
            app.EnteroffsetvalueinmmforgridDefault2mmLabel = uilabel(app.UIFigure);
            app.EnteroffsetvalueinmmforgridDefault2mmLabel.Text = 'Enter offset value in mm for grid. (Default: 2 mm)';
            app.Offset_Info = uilabel(app.UIFigure);
            app.Offset_Info.Text = '';
            app.Offset_Info_2 = uilabel(app.UIFigure);
            app.Offset_Info_2.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tooltip = {'Analysis begins from the second row and column; the first row and column defined by the offset are excluded from the results.'};
            app.GridReferenceImageLabel = uilabel(app.UIFigure);
            app.GridReferenceImageLabel.Text = 'Grid Reference Image:';
            app.AllFramesButton = uibutton(app.UIFigure, 'push');
            app.AllFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AllFramesButtonPushed, true);
            app.AllFramesButton.Tooltip = {'Select all frames'};
            app.AllFramesButton.Text = 'All Frames';
            app.OKButton_FrameRange = uibutton(app.UIFigure, 'push');
            app.OKButton_FrameRange.ButtonPushedFcn = createCallbackFcn(app, @OKButton_FrameRangePushed, true);
            app.OKButton_FrameRange.Tooltip = {'Confirm range selection'};
            app.OKButton_FrameRange.Text = 'OK';
            app.SelectallframesorframerangeDefaultAllFramesLabel = uilabel(app.UIFigure);
            app.SelectallframesorframerangeDefaultAllFramesLabel.Text = 'Select all frames or frame range. (Default: All Frames)';
            app.FrameRangeLabel = uilabel(app.UIFigure);
            app.FrameRangeLabel.Text = '';
            app.NoofFramesLabel = uilabel(app.UIFigure);
            app.NoofFramesLabel.Text = 'No. of Frames:';
            app.NoofFramesTextArea = uitextarea(app.UIFigure);
            app.NoofFramesTextArea.ValueChangedFcn = createCallbackFcn(app, @NoofFramesTextAreaValueChanged, true);
            app.NoofFramesTextArea.Editable = 'off';
            app.ImportedFileTextAreaLabel = uilabel(app.UIFigure);
            app.ImportedFileTextAreaLabel.Text = 'Imported File:';
            app.ImportedFileTextArea = uitextarea(app.UIFigure);
            app.ImportedFileTextArea.ValueChangedFcn = createCallbackFcn(app, @ImportedFileTextAreaValueChanged, true);
            app.ImportedFileTextArea.Editable = 'off';
            app.ViewFrameLabel = uilabel(app.UIFigure);
            app.ViewFrameLabel.Text = 'Frame No.:';
            app.FrameNoSpinner = uispinner(app.UIFigure);
            app.FrameNoSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameNoSpinnerValueChanged, true);
            app.FrameNoSpinner.Tooltip = {'Choose frame to view'};
            app.GridOffsetxLabel = uilabel(app.UIFigure);
            app.GridOffsetxLabel.Text = 'Grid Offset X:';
            app.GridOffset_xSpinner = uispinner(app.UIFigure);
            app.GridOffset_xSpinner.Step = 0.1;
            app.GridOffset_xSpinner.ValueChangedFcn = createCallbackFcn(app, @GridOffset_xSpinnerValueChanged, true);
            app.GridOffset_xSpinner.Tooltip = {'Set X offset for grid in mm'};
            app.GridOffsetyLabel = uilabel(app.UIFigure);
            app.GridOffsetyLabel.Text = 'Grid Offset Y:';
            app.GridOffset_ySpinner = uispinner(app.UIFigure);
            app.GridOffset_ySpinner.Step = 0.1;
            app.GridOffset_ySpinner.ValueChangedFcn = createCallbackFcn(app, @GridOffset_ySpinnerValueChanged, true);
            app.GridOffset_ySpinner.Tooltip = {'Set Y offset for grid in mm'};
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.FromEditField.Tooltip = {'First frame'};
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to:';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Tooltip = {'Last frame'};
            app.DrawRectangleButton = uibutton(app.UIFigure, 'push');
            app.DrawRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRectangleButtonPushed, true);
            app.DrawRectangleButton.Enable = 'off';
            app.DrawRectangleButton.Tooltip = {'Draw rectangle'};
            app.DrawRectangleButton.Text = '';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'refresh.png';
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Tooltip = {'Refresh ROI selection'};
            app.RefreshButton.Text = '';
            app.OKButtonROI = uibutton(app.UIFigure, 'state');
            app.OKButtonROI.ValueChangedFcn = createCallbackFcn(app, @OKButtonROIValueChanged, true);
            app.OKButtonROI.Enable = 'off';
            app.OKButtonROI.Tooltip = {'Confirm ROI selection'};
            app.OKButtonROI.Text = 'OK';
            app.ROISelectionLabel = uilabel(app.UIFigure);
            app.ROISelectionLabel.Text = 'ROI Selection';
            app.CurrentdirectoryLabel = uilabel(app.UIFigure);
            app.CurrentdirectoryLabel.Text = 'Current directory:';
            app.noUltrasoundLabel = uilabel(app.UIFigure);
            app.noUltrasoundLabel.Text = '';
            app.SWEImageAnalysisToolLabel = uilabel(app.UIFigure);
            app.SWEImageAnalysisToolLabel.Text = 'SWE Image Analysis Tool';
            app.ImportFolder = uibutton(app.UIFigure, 'push');
            app.ImportFolder.ButtonPushedFcn = createCallbackFcn(app, @ImportFolderButtonPushed, true);
            app.ImportFolder.Tooltip = {'Import folder containing multiple DICOM files'};
            app.ImportFolder.Text = '';
            app.ImportLabel_2 = uilabel(app.UIFigure);
            app.ImportLabel_2.Text = 'Import Multiple';
            app.ManualsegmentationButton = uibutton(app.UIFigure, 'push');
            app.ManualsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualsegmentationButtonPushed, true);
            app.ManualsegmentationButton.Text = {'Manual'; 'segmentation'};
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max ';
            app.MaxEditField = uieditfield(app.UIFigure, 'text');
            app.MaxEditField.Value = '300';
            app.FullimagesasROIselectiononlyNoGridpossibleLabel = uilabel(app.UIFigure);
            app.FullimagesasROIselectiononlyNoGridpossibleLabel.Text = 'Full images as ROI selection only! No Grid possible!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end